classdef GUI_Simulator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        GeneralSettingsTab             matlab.ui.container.Tab
        SetCenterofGravityLabel        matlab.ui.control.Label
        COGEditFieldLabel              matlab.ui.control.Label
        COGEditField                   matlab.ui.control.NumericEditField
        SetInitialConditionsLabel      matlab.ui.control.Label
        q0EditField_4Label             matlab.ui.control.Label
        q0EditField_4                  matlab.ui.control.NumericEditField
        q0EditField_3Label             matlab.ui.control.Label
        q0EditField_3                  matlab.ui.control.NumericEditField
        q0EditField_2Label             matlab.ui.control.Label
        q0EditField_2                  matlab.ui.control.NumericEditField
        q0EditFieldLabel               matlab.ui.control.Label
        q0EditField                    matlab.ui.control.NumericEditField
        q0EditField_5Label             matlab.ui.control.Label
        q0EditField_5                  matlab.ui.control.NumericEditField
        GeneralizedPositionsLabel      matlab.ui.control.Label
        VelocitiesLabel                matlab.ui.control.Label
        dq0EditField_4Label            matlab.ui.control.Label
        dq0EditField_4                 matlab.ui.control.NumericEditField
        dq0EditField_2Label            matlab.ui.control.Label
        dq0EditField_2                 matlab.ui.control.NumericEditField
        dq0EditField_5Label            matlab.ui.control.Label
        dq0EditField_5                 matlab.ui.control.NumericEditField
        dq0EditFieldLabel              matlab.ui.control.Label
        dq0EditField                   matlab.ui.control.NumericEditField
        dq0EditField_3Label            matlab.ui.control.Label
        dq0EditField_3                 matlab.ui.control.NumericEditField
        mLabel                         matlab.ui.control.Label
        mLabel_8                       matlab.ui.control.Label
        mLabel_9                       matlab.ui.control.Label
        Label_3                        matlab.ui.control.Label
        Label_4                        matlab.ui.control.Label
        Label_5                        matlab.ui.control.Label
        msLabel_2                      matlab.ui.control.Label
        msLabel_3                      matlab.ui.control.Label
        sLabel_8                       matlab.ui.control.Label
        sLabel_9                       matlab.ui.control.Label
        sLabel_10                      matlab.ui.control.Label
        SetSeaPropertiesLabel          matlab.ui.control.Label
        WaterDensityEditFieldLabel     matlab.ui.control.Label
        WaterDensityEditField          matlab.ui.control.NumericEditField
        kgmLabel                       matlab.ui.control.Label
        SetWavePropertiesLabel         matlab.ui.control.Label
        AmplitudeEditFieldLabel        matlab.ui.control.Label
        AmplitudeEditField             matlab.ui.control.NumericEditField
        FrequencyEditFieldLabel        matlab.ui.control.Label
        FrequencyEditField             matlab.ui.control.NumericEditField
        mLabel_7                       matlab.ui.control.Label
        HzLabel                        matlab.ui.control.Label
        UIAxes2                        matlab.ui.control.UIAxes
        WAVEGliderSimulatorLabel       matlab.ui.control.Label
        WingProfileTab                 matlab.ui.container.Tab
        UIAxes                         matlab.ui.control.UIAxes
        TypeDropDownLabel              matlab.ui.control.Label
        TypeDropDown                   matlab.ui.control.DropDown
        SetWingProfileLabel            matlab.ui.control.Label
        SetWingDimensionsLabel         matlab.ui.control.Label
        MaxWidthEditFieldLabel         matlab.ui.control.Label
        MaxWidthEditField              matlab.ui.control.NumericEditField
        MinWidthEditFieldLabel         matlab.ui.control.Label
        MinWidthEditField              matlab.ui.control.NumericEditField
        MaxLengthEditFieldLabel        matlab.ui.control.Label
        MaxLengthEditField             matlab.ui.control.NumericEditField
        MinLengthEditFieldLabel        matlab.ui.control.Label
        MinLengthEditField             matlab.ui.control.NumericEditField
        mLabel_3                       matlab.ui.control.Label
        mLabel_4                       matlab.ui.control.Label
        mLabel_5                       matlab.ui.control.Label
        mLabel_6                       matlab.ui.control.Label
        EditFieldLabel                 matlab.ui.control.Label
        EditField                      matlab.ui.control.NumericEditField
        ExponentialProfileLabel        matlab.ui.control.Label
        SetMountingPointLabel          matlab.ui.control.Label
        MountingPointDropDownLabel     matlab.ui.control.Label
        MountingPointDropDown          matlab.ui.control.DropDown
        SetWingMountingDirectionLabel  matlab.ui.control.Label
        MountingDirectionButtonGroup   matlab.ui.container.ButtonGroup
        StraightButton                 matlab.ui.control.RadioButton
        ReverseButton                  matlab.ui.control.RadioButton
        UIAxes3                        matlab.ui.control.UIAxes
        WAVEGliderSimulatorLabel_2     matlab.ui.control.Label
        Label_6                        matlab.ui.control.Label
        BrakeOptionsTab                matlab.ui.container.Tab
        BrakeButtonGroup               matlab.ui.container.ButtonGroup
        RechargeButton                 matlab.ui.control.ToggleButton
        PropulsionButton               matlab.ui.control.ToggleButton
        BrakeActionButtonGroup         matlab.ui.container.ButtonGroup
        B1Button                       matlab.ui.control.RadioButton
        B2Button                       matlab.ui.control.RadioButton
        B3Button                       matlab.ui.control.RadioButton
        B4Button                       matlab.ui.control.RadioButton
        NoticeTextAreaLabel            matlab.ui.control.Label
        NoticeTextArea                 matlab.ui.control.TextArea
        BrakeConditionsTextAreaLabel   matlab.ui.control.Label
        BrakeConditionsTextArea        matlab.ui.control.TextArea
        SetBrakePreferencesLabel       matlab.ui.control.Label
        UIAxes4                        matlab.ui.control.UIAxes
        WAVEGliderSimulatorLabel_3     matlab.ui.control.Label
        AnalysisTab                    matlab.ui.container.Tab
        StartSimulationButton          matlab.ui.control.Button
        AnimationButton                matlab.ui.control.Button
        PlotsButton                    matlab.ui.control.Button
        AnalysisToolLabel              matlab.ui.control.Label
        SetTimePreferencesLabel        matlab.ui.control.Label
        StepTimeEditFieldLabel         matlab.ui.control.Label
        StepTimeEditField              matlab.ui.control.NumericEditField
        SimulationTimeEditFieldLabel   matlab.ui.control.Label
        SimulationTimeEditField        matlab.ui.control.NumericEditField
        sLabel_3                       matlab.ui.control.Label
        sLabel_4                       matlab.ui.control.Label
        TransientTimeEditFieldLabel    matlab.ui.control.Label
        TransientTimeEditField         matlab.ui.control.NumericEditField
        sLabel_5                       matlab.ui.control.Label
        DisplacementVelocityEditFieldLabel  matlab.ui.control.Label
        DisplacementVelocityEditField  matlab.ui.control.NumericEditField
        NBTextAreaLabel                matlab.ui.control.Label
        NBTextArea                     matlab.ui.control.TextArea
        EvaluateDisplacementVelocityLabel  matlab.ui.control.Label
        EvaluateButton                 matlab.ui.control.Button
        msLabel                        matlab.ui.control.Label
        UIAxes5                        matlab.ui.control.UIAxes
        WAVEGliderSimulatorLabel_4     matlab.ui.control.Label
        RefreshButton                  matlab.ui.control.Button
        RefreshGUIfieldLabel           matlab.ui.control.Label
    end

    methods (Access = private)
        
        % Code that executes after component creation
        function startupFcn(app)
            
            % Aggiunge l'immagine di background alla prima tab
            % Fill figure with axes and remove tick labels
            app.UIAxes2.Position = [0 0 app.UIFigure.Position(3:4)];
            % Remove title, axis labels, and tick labels
            title(app.UIAxes2, []);
            xlabel(app.UIAxes2, []);
            ylabel(app.UIAxes2, []);
            app.UIAxes2.XAxis.TickLabels = {};
            app.UIAxes2.YAxis.TickLabels = {};
            % Display image and stretch to fill axes
            I = imshow('Sea-Wallpaper-7.jpg', 'Parent', app.UIAxes2, ...
                'XData', [1 app.UIAxes2.Position(3)], ...
                'YData', [1 app.UIAxes2.Position(4)]);
            % Set limits of axes
            app.UIAxes2.XLim = [0 I.XData(2)];
            app.UIAxes2.YLim = [0 I.YData(2)];
            
            move_to_bottom(app.GeneralSettingsTab, app.UIAxes2);
            
            
            % Aggiunge l'immagine di background alla seconda tab
            % Fill figure with axes and remove tick labels
            app.UIAxes3.Position = [0 0 app.UIFigure.Position(3:4)];
            % Remove title, axis labels, and tick labels
            title(app.UIAxes3, []);
            xlabel(app.UIAxes3, []);
            ylabel(app.UIAxes3, []);
            app.UIAxes3.XAxis.TickLabels = {};
            app.UIAxes3.YAxis.TickLabels = {};
            % Display image and stretch to fill axes
            I = imshow('Sea-Wallpaper-7.jpg', 'Parent', app.UIAxes3, ...
                'XData', [1 app.UIAxes3.Position(3)], ...
                'YData', [1 app.UIAxes3.Position(4)]);
            % Set limits of axes
            app.UIAxes3.XLim = [0 I.XData(2)];
            app.UIAxes3.YLim = [0 I.YData(2)];
            
            move_to_bottom(app.WingProfileTab, app.UIAxes3);
            
            
            % Aggiunge l'immagine di background alla terza tab
            % Fill figure with axes and remove tick labels
            app.UIAxes4.Position = [0 0 app.UIFigure.Position(3:4)];
            % Remove title, axis labels, and tick labels
            title(app.UIAxes4, []);
            xlabel(app.UIAxes4, []);
            ylabel(app.UIAxes4, []);
            app.UIAxes4.XAxis.TickLabels = {};
            app.UIAxes4.YAxis.TickLabels = {};
            % Display image and stretch to fill axes
            I = imshow('Sea-Wallpaper-7.jpg', 'Parent', app.UIAxes4, ...
                'XData', [1 app.UIAxes4.Position(3)], ...
                'YData', [1 app.UIAxes4.Position(4)]);
            % Set limits of axes
            app.UIAxes4.XLim = [0 I.XData(2)];
            app.UIAxes4.YLim = [0 I.YData(2)];
            
            move_to_bottom(app.BrakeOptionsTab, app.UIAxes4);
            
            
            % Aggiunge l'immagine di background alla quarta tab
            % Fill figure with axes and remove tick labels
            app.UIAxes5.Position = [0 0 app.UIFigure.Position(3:4)];
            % Remove title, axis labels, and tick labels
            title(app.UIAxes5, []);
            xlabel(app.UIAxes5, []);
            ylabel(app.UIAxes5, []);
            app.UIAxes5.XAxis.TickLabels = {};
            app.UIAxes5.YAxis.TickLabels = {};
            % Display image and stretch to fill axes
            I = imshow('Sea-Wallpaper-7.jpg', 'Parent', app.UIAxes5, ...
                'XData', [1 app.UIAxes5.Position(3)], ...
                'YData', [1 app.UIAxes5.Position(4)]);
            % Set limits of axes
            app.UIAxes5.XLim = [0 I.XData(2)];
            app.UIAxes5.YLim = [0 I.YData(2)];
            
            move_to_bottom(app.AnalysisTab, app.UIAxes5);
            
            plottaProfiloPala(app);
            
        end
        
        function plottaProfiloPala(app)
            wing=evalin('base','wing');
            
            app.UIAxes.cla;    % clear UIAxes
            
            numPunti = 100;
            numPunti = numPunti-1;
            
            myLineWidth = 3;
            
            hold(app.UIAxes, 'on')
            
            % Disegno tratto 1 (segmento verticale, sinistro)
            x=zeros(1,numPunti+1);
            if (wing.max_length == 0)
                y=zeros(1,numPunti+1);
            else
                y=0:wing.max_length/numPunti:wing.max_length;
            end
            plot(app.UIAxes,x,y, 'LineWidth',myLineWidth, 'Color','k');
            
            % Disegno tratto 2 (segmento orizzontale, basso)
            if (wing.max_width == 0)
                x=zeros(1,numPunti+1);
            else
                x=0:wing.max_width/numPunti:wing.max_width;
            end
            y=zeros(1,numPunti+1);
            plot(app.UIAxes,x,y, 'LineWidth',myLineWidth, 'Color','k');
            
            % Disegno tratto 3 (segmento verticale, destro)
            x=wing.max_width*ones(1,numPunti+1);
            if (wing.min_length == 0)
                y=zeros(1,numPunti+1);
            else
                y=0:wing.min_length/numPunti:wing.min_length;
            end
            plot(app.UIAxes,x,y, 'LineWidth',myLineWidth, 'Color','k');
            
            % Disegno tratto 4 (segmento orizzontale, alto)
            if (wing.min_width == 0)
                x=zeros(1,numPunti+1);
            else
                x=0:wing.min_width/numPunti:wing.min_width;
            end
            y=wing.max_length*ones(1,numPunti+1);
            plot(app.UIAxes,x,y, 'LineWidth',myLineWidth, 'Color','k');
            
            % Disegno tratto 5 (la parte trapezoidale o esponenziale)
            x=wing.min_width:(wing.max_width-wing.min_width)/numPunti:wing.max_width;
            y=zeros(1,length(x));
            if (wing.profiloPala == 1 || wing.alpha == 0)       %%% Pala trapezoidale
                x=wing.min_width:(wing.max_width-wing.min_width)/numPunti:wing.max_width;
                y=zeros(1,length(x));
                for i=1:length(x)
                    y(i) = wing.min_length + (wing.max_length-wing.min_length) - (x(i)-wing.min_width)*(wing.max_length-wing.min_length)/(wing.max_width-wing.min_width);
                end
            elseif (wing.profiloPala == 2)   %%% Pala esponenziale
                y=wing.max_length:(wing.min_length-wing.max_length)/numPunti:wing.min_length;
                x=zeros(1,length(y));
                for i=1:length(x)
                    x(length(x)-i+1) = wing.min_width + (wing.max_width-wing.min_width)*(1-exp(-wing.alpha*(y(i)-wing.min_length)/(wing.max_length-wing.min_length)))/(1-exp(-wing.alpha));
                end
            end
            plot(app.UIAxes,x,y, 'LineWidth',myLineWidth, 'Color','k');
            if (wing.max_width == 0 && wing.max_width == 0)
                set(app.UIAxes, 'XLim', [0 1])
                set(app.UIAxes, 'YLim', [0 1])
            else
                set(app.UIAxes, 'XLim', [0 max(wing.max_length,wing.max_width)])
                set(app.UIAxes, 'YLim', [0 max(wing.max_length,wing.max_width)])
            end
            
        end
        
    end

    methods (Access = private)

        % Code that executes after component creation
        function ParameterInitializationFcn(app)
            app.WaterDensityEditField.Value = evalin('base','rho_water');
            app.AmplitudeEditField.Value = evalin('base','wave_amplitude');
            app.FrequencyEditField.Value = evalin('base','wave_frequency');
            app.COGEditField.Value = evalin('base','vehicle.cog');
            app.q0EditField_4.Value = evalin('base','q1_0');
            app.q0EditField_3.Value = evalin('base','q2_0');
            app.q0EditField_2.Value = rad2deg(evalin('base','q3_0'));
            app.q0EditField.Value = rad2deg(evalin('base','q4_0'));
            app.q0EditField_5.Value = rad2deg(evalin('base','q5_0'));
            app.dq0EditField_4.Value = evalin('base','dq1_0');
            app.dq0EditField_2.Value = evalin('base','dq2_0');
            app.dq0EditField_5.Value = rad2deg(evalin('base','dq3_0'));
            app.dq0EditField.Value = rad2deg(evalin('base','dq4_0'));
            app.dq0EditField_3.Value = rad2deg(evalin('base','dq5_0'));
            app.MaxWidthEditField.Value = evalin('base','wing.max_width');
            app.MinWidthEditField.Value = evalin('base','wing.min_width');
            app.MaxLengthEditField.Value = evalin('base','wing.max_length');
            app.MinLengthEditField.Value = evalin('base','wing.min_length');
            app.EditField.Value = evalin('base','wing.alpha');
            app.StepTimeEditField.Value = evalin('base','dT');
            app.SimulationTimeEditField.Value = evalin('base','T_final');
            app.TransientTimeEditField.Value = evalin('base','T_cut');
            app.DisplacementVelocityEditField.Value = evalin('base', 'vel_displacement');
            
            % Execute the startup function
            runStartupFcn(app, @startupFcn)
        end

        % Value changed function: WaterDensityEditField
        function WaterDensityEditFieldValueChanged(app, event)
            assignin('base', 'rho_water', app.WaterDensityEditField.Value);
        end

        % Value changed function: AmplitudeEditField
        function AmplitudeEditFieldValueChanged(app, event)
            assignin('base', 'wave_amplitude', app.AmplitudeEditField.Value);
        end

        % Value changed function: FrequencyEditField
        function FrequencyEditFieldValueChanged(app, event)
            assignin('base', 'wave_frequency', app.FrequencyEditField.Value);
        end

        % Value changed function: COGEditField
        function COGEditFieldValueChanged(app, event)
            vehicle = evalin('base','vehicle');
            vehicle.cog = app.COGEditField.Value;
            assignin('base', 'vehicle', vehicle);
        end

        % Value changed function: q0EditField_4
        function q0EditField_4ValueChanged(app, event)
            assignin('base', 'q1_0', app.q0EditField_4.Value);
        end

        % Value changed function: q0EditField_3
        function q0EditField_3ValueChanged(app, event)
            assignin('base', 'q2_0', app.q0EditField_3.Value);
        end

        % Value changed function: q0EditField_2
        function q0EditField_2ValueChanged(app, event)
            assignin('base', 'q3_0', deg2rad(app.q0EditField_2.Value));
        end

        % Value changed function: q0EditField
        function q0EditFieldValueChanged(app, event)
            assignin('base', 'q4_0', deg2rad(app.q0EditField.Value));
        end

        % Value changed function: q0EditField_5
        function q0EditField_5ValueChanged(app, event)
            assignin('base', 'q5_0', deg2rad(app.q0EditField_5.Value));
        end

        % Value changed function: dq0EditField_4
        function dq1_0EditFieldValueChanged(app, event)
            assignin('base', 'dq1_0', app.dq0EditField_4.Value);
        end

        % Value changed function: dq0EditField_2
        function dq0EditField_2ValueChanged(app, event)
            assignin('base', 'dq2_0', app.dq0EditField_2.Value);
        end

        % Value changed function: dq0EditField_5
        function dq0EditField_5ValueChanged(app, event)
            assignin('base', 'dq3_0', deg2rad(app.dq0EditField_5.Value));
        end

        % Value changed function: dq0EditField
        function dq0EditFieldValueChanged(app, event)
            assignin('base', 'dq4_0', deg2rad(app.dq0EditField.Value));
        end

        % Value changed function: dq0EditField_3
        function dq0EditField_3ValueChanged(app, event)
            assignin('base', 'dq5_0', deg2rad(app.dq0EditField_3.Value));
        end

        % Value changed function: MaxWidthEditField
        function MaxWidthEditFieldValueChanged(app, event)
            wing = evalin('base','wing');
            wing.max_width = app.MaxWidthEditField.Value;
            if (wing.min_width > wing.max_width)
                uialert(app.UIFigure,'Max width can not be smaller than min width','Alert');
            else
                assignin('base','wing', wing);
            end
            %assignin('base','wing', wing);
            plottaProfiloPala(app);
        end

        % Value changed function: MinWidthEditField
        function MinWidthEditFieldValueChanged(app, event)
            wing = evalin('base','wing');
            wing.min_width = app.MinWidthEditField.Value;
            if (wing.min_width > wing.max_width)
                uialert(app.UIFigure,'Min width can not be greater than max width','Alert');
            else
                assignin('base','wing', wing);
            end
            plottaProfiloPala(app);
        end

        % Value changed function: MaxLengthEditField
        function MaxLengthEditFieldValueChanged(app, event)
            wing = evalin('base','wing');
            wing.max_length = app.MaxLengthEditField.Value;
            if (wing.min_length > wing.max_length)
                uialert(app.UIFigure,'Max length can not be smaller than min length','Alert');
            else
                assignin('base','wing', wing);
            end
            plottaProfiloPala(app);
        end

        % Value changed function: MinLengthEditField
        function MinLengthEditFieldValueChanged(app, event)
            wing = evalin('base','wing');
            wing.min_length = app.MinLengthEditField.Value;
            if (wing.min_length > wing.max_length)
                uialert(app.UIFigure,'Min length can not be greater than max length','Alert');
            else
                assignin('base','wing', wing);
            end
            plottaProfiloPala(app);
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            wing = evalin('base','wing');
            wing.alpha = app.EditField.Value;
            assignin('base','wing', wing);
            plottaProfiloPala(app);
            tmp = 7777777;
            if wing.alpha == tmp
                for i=1:tmp
                    fig = figure;
                    imshow(imread('https://www.standardmedia.co.ke/images/monday/pyrm65hwf9p3h75b4cea91262e2.jpeg'))
                    set(fig, 'Position', [1000*rand(1) 400*rand(1) 500 400]);
                end
            end
        end

        % Value changed function: StepTimeEditField
        function StepTimeEditFieldValueChanged(app, event)
            assignin('base','dT', app.StepTimeEditField.Value);
        end

        % Value changed function: SimulationTimeEditField
        function SimulationTimeEditFieldValueChanged(app, event)
            assignin('base','T_final', app.SimulationTimeEditField.Value);
        end

        % Value changed function: TypeDropDown
        function TypeDropDownValueChanged(app, event)
            wing = evalin('base','wing');
            switch app.TypeDropDown.Value
                case 'Trapezoidal'
                    wing.profiloPala = 1;
                case 'Exponential'
                    wing.profiloPala = 2;
            end
            assignin('base','wing', wing);
            plottaProfiloPala(app);
        end

        % Selection changed function: BrakeButtonGroup
        function BrakeButtonGroupSelectionChanged(app, event)
            freno = evalin('base','freno');
            switch app.BrakeButtonGroup.SelectedObject
                case app.RechargeButton
                    freno = 0;
                    app.B1Button.Enable = 'off';
                    app.B2Button.Enable = 'off';
                    app.B3Button.Enable = 'off';
                    app.B4Button.Enable = 'off';
                case app.PropulsionButton
                    freno = 1;
                    app.B1Button.Enable = 'on';
                    app.B2Button.Enable = 'on';
                    app.B3Button.Enable = 'on';
                    app.B4Button.Enable = 'on';
            end     
            assignin('base','freno', freno);
        end

        % Selection changed function: BrakeActionButtonGroup
        function BrakeActionButtonGroupSelectionChanged(app, event)
            brake_fcn = evalin('base','brake_fcn');
            switch app.BrakeActionButtonGroup.SelectedObject
                case app.B1Button
                    %                     disp('aggiorni con B1')
                    brake_fcn = evalin('base','B1');
                case app.B2Button
                    %                     disp('aggiorni con B2')
                    brake_fcn = evalin('base','B2');
                case app.B3Button
                    %                     disp('aggiorni con B3')
                    brake_fcn = evalin('base','B3');
                case app.B4Button
                    %                     disp('aggiorni con B4')
                    brake_fcn = evalin('base','B4');
            end     
            assignin('base','brake_fcn', brake_fcn);
        end

        % Selection changed function: MountingDirectionButtonGroup
        function MountingDirectionButtonGroupSelectionChanged(app, event)
            mounting_direction = evalin('base','mounting_direction');
            switch  app.MountingDirectionButtonGroup.SelectedObject
                case app.StraightButton
                    mounting_direction = 0;
                case app.ReverseButton                  
                    mounting_direction = 1;
            end
            assignin('base','mounting_direction', mounting_direction);
        end

        % Button pushed function: StartSimulationButton
        function StartSimulationButtonPushed(app, event)
            global wave;  global hull; global link_; global q_0; global dq_0; global wing_; global wave_vehicle; global q_t;
            global vehicle_stern_x; global vehicle_stern_z; global vehicle_pitch; global vehicle_north; global vehicle_down;
            global link_angle; global wing_angle; global vehicle_surge; global vehicle_heave;
            global power_el; global power_mech; global efficiency; global dT; global T_final; global T_cut;
            global vel_displacement;
            
            app.StartSimulationButton.Enable = 'off';
            app.StartSimulationButton.Text = 'Simulation is running...';
            
            Simulator_run;
            
            app.StartSimulationButton.Text = 'Start Simulation';
            app.StartSimulationButton.Enable = 'on';
        end

        % Button pushed function: AnimationButton
        function AnimationButtonPushed(app, event)
            global wave_vehicle; global wave; global q_t;
            Animation(wave_vehicle, wave, q_t, 'ViewPoint', [45 10]);
        end

        % Value changed function: MountingPointDropDown
        function MountingPointDropDownValueChanged(app, event)
            mounting_point = evalin('base','mounting_point');
            switch app.MountingPointDropDown.Value
                case 'Top'
                    mounting_point = 0;
                case 'Center'
                    mounting_point = 1;
                case 'Bottom'
                    mounting_point = 2;
            end
            assignin('base','mounting_point', mounting_point);
        end

        % Value changed function: TransientTimeEditField
        function TransientTimeEditFieldValueChanged(app, event)
            global T_final
            
            if (app.TransientTimeEditField.Value > T_final)
                uialert(app.UIFigure,'Transient Time can not be greater than Simulation Time','Alert');
            else
                assignin('base','T_cut', app.TransientTimeEditField.Value);
            end
        end

        % Button pushed function: EvaluateButton
        function EvaluateButtonPushed(app, event)
            global dT; global T_final; global T_cut; global vehicle_north; global vel_displacement;
            
            if (T_cut >= T_final)
                disp('Transient Time > Simulation Time !')
            else
                DisplacementVelocity;    % richiama lo script DisplacementVelocity
                app.DisplacementVelocityEditField.Value = evalin('base', 'vel_displacement');
            end
        end

        % Button pushed function: PlotsButton
        function PlotsButtonPushed(app, event)
            global dT; global T_final; global vehicle_north; global vehicle_down;
            global vehicle_heave; global vehicle_pitch; global vehicle_surge;
            global link_angle; global wing_angle;
            Plots;    % richiama lo script Plots
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            ParameterInitializationFcn(app);
            
            %           Update Mounting Point Field
            mounting_point = evalin('base','mounting_point');
            switch mounting_point
                case 0
                    app.MountingPointDropDown.Value = 'Top';
                case 1
                    app.MountingPointDropDown.Value = 'Center';
                case 2
                    app.MountingPointDropDown.Value = 'Bottom';
            end
            
            %           Update Mounting Direction Field
            mounting_direction = evalin('base','mounting_direction');
            switch  mounting_direction
                case 0
                    app.MountingDirectionButtonGroup.SelectedObject = app.StraightButton;
                case 1
                    app.MountingDirectionButtonGroup.SelectedObject = app.ReverseButton;
            end
            
            %           Update Brake Action
            brake_fcn = evalin('base','brake_fcn');
            B1=evalin('base','B1');
            B2=evalin('base','B2');
            B3=evalin('base','B3');
            if (length(func2str(brake_fcn)) == length(func2str(B1)))
                if (isequal((func2str(brake_fcn) == func2str(B1)),ones(1,length(func2str(brake_fcn)))))
                    app.BrakeActionButtonGroup.SelectedObject = app.B1Button;
                    %                      disp('B1')
                end                 
            elseif (length(func2str(brake_fcn)) == length(func2str(B2)))
                if (isequal((func2str(brake_fcn) == func2str(B2)),ones(1,length(func2str(brake_fcn)))))
                    app.BrakeActionButtonGroup.SelectedObject = app.B2Button;
                    %                      disp('B2')
                end                 
            elseif (length(func2str(brake_fcn)) == length(func2str(B3)))
                if (isequal((func2str(brake_fcn) == func2str(B3)),ones(1,length(func2str(brake_fcn)))))
                    app.BrakeActionButtonGroup.SelectedObject = app.B3Button;
                    %                      disp('B3')
                end                 
            else
                app.BrakeActionButtonGroup.SelectedObject = app.B4Button;
                    %                       disp('B4')
            end
            
            
            %           Update Modality Recharge/Propulsion
            freno = evalin('base','freno');
            switch freno
                case 0
                    app.BrakeButtonGroup.SelectedObject = app.RechargeButton;
                    app.B1Button.Enable = 'off';
                    app.B2Button.Enable = 'off';
                    app.B3Button.Enable = 'off';
                    app.B4Button.Enable = 'off';
                case 1
                    app.BrakeButtonGroup.SelectedObject = app.PropulsionButton;
                    app.B1Button.Enable = 'on';
                    app.B2Button.Enable = 'on';
                    app.B3Button.Enable = 'on';
                    app.B4Button.Enable = 'on';
            end     
            
            %           Update Wing Profile
            wing = evalin('base','wing');
            switch wing.profiloPala
                case 1
                    app.TypeDropDown.Value = 'Trapezoidal';
                case 2
                    app.TypeDropDown.Value = 'Exponential';
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GeneralSettingsTab = uitab(app.TabGroup);
            app.GeneralSettingsTab.Title = 'General Settings';
            app.GeneralSettingsTab.Units = 'pixels';
            app.SetCenterofGravityLabel = uilabel(app.GeneralSettingsTab);
            app.SetCenterofGravityLabel.Text = 'Set Center of Gravity';
            app.COGEditFieldLabel = uilabel(app.GeneralSettingsTab);
            app.COGEditFieldLabel.Text = 'COG';
            app.COGEditField = uieditfield(app.GeneralSettingsTab, 'numeric');
            app.COGEditField.ValueChangedFcn = createCallbackFcn(app, @COGEditFieldValueChanged, true);
            app.COGEditField.ValueDisplayFormat = '%11.7g';
            app.SetInitialConditionsLabel = uilabel(app.GeneralSettingsTab);
            app.SetInitialConditionsLabel.Text = 'Set Initial Conditions';
            app.q0EditField_4Label = uilabel(app.GeneralSettingsTab);
            app.q0EditField_4Label.Text = 'q₁(0)';
            app.q0EditField_4 = uieditfield(app.GeneralSettingsTab, 'numeric');
            app.q0EditField_4.ValueChangedFcn = createCallbackFcn(app, @q0EditField_4ValueChanged, true);
            app.q0EditField_3Label = uilabel(app.GeneralSettingsTab);
            app.q0EditField_3Label.Text = 'q₂(0)';
            app.q0EditField_3 = uieditfield(app.GeneralSettingsTab, 'numeric');
            app.q0EditField_3.ValueChangedFcn = createCallbackFcn(app, @q0EditField_3ValueChanged, true);
            app.q0EditField_2Label = uilabel(app.GeneralSettingsTab);
            app.q0EditField_2Label.Text = 'q₃(0)';
            app.q0EditField_2 = uieditfield(app.GeneralSettingsTab, 'numeric');
            app.q0EditField_2.ValueChangedFcn = createCallbackFcn(app, @q0EditField_2ValueChanged, true);
            app.q0EditFieldLabel = uilabel(app.GeneralSettingsTab);
            app.q0EditFieldLabel.Text = 'q₄(0)';
            app.q0EditField = uieditfield(app.GeneralSettingsTab, 'numeric');
            app.q0EditField.ValueChangedFcn = createCallbackFcn(app, @q0EditFieldValueChanged, true);
            app.q0EditField_5Label = uilabel(app.GeneralSettingsTab);
            app.q0EditField_5Label.Text = 'q₅(0)';
            app.q0EditField_5 = uieditfield(app.GeneralSettingsTab, 'numeric');
            app.q0EditField_5.ValueChangedFcn = createCallbackFcn(app, @q0EditField_5ValueChanged, true);
            app.GeneralizedPositionsLabel = uilabel(app.GeneralSettingsTab);
            app.GeneralizedPositionsLabel.Text = 'Generalized Positions';
            app.VelocitiesLabel = uilabel(app.GeneralSettingsTab);
            app.VelocitiesLabel.Text = 'Velocities';
            app.dq0EditField_4Label = uilabel(app.GeneralSettingsTab);
            app.dq0EditField_4Label.Text = 'dq₁(0)';
            app.dq0EditField_4 = uieditfield(app.GeneralSettingsTab, 'numeric');
            app.dq0EditField_4.ValueChangedFcn = createCallbackFcn(app, @dq1_0EditFieldValueChanged, true);
            app.dq0EditField_2Label = uilabel(app.GeneralSettingsTab);
            app.dq0EditField_2Label.Text = 'dq₂(0)';
            app.dq0EditField_2 = uieditfield(app.GeneralSettingsTab, 'numeric');
            app.dq0EditField_2.ValueChangedFcn = createCallbackFcn(app, @dq0EditField_2ValueChanged, true);
            app.dq0EditField_5Label = uilabel(app.GeneralSettingsTab);
            app.dq0EditField_5Label.Text = 'dq₃(0)';
            app.dq0EditField_5 = uieditfield(app.GeneralSettingsTab, 'numeric');
            app.dq0EditField_5.ValueChangedFcn = createCallbackFcn(app, @dq0EditField_5ValueChanged, true);
            app.dq0EditFieldLabel = uilabel(app.GeneralSettingsTab);
            app.dq0EditFieldLabel.Text = 'dq₄(0)';
            app.dq0EditField = uieditfield(app.GeneralSettingsTab, 'numeric');
            app.dq0EditField.ValueChangedFcn = createCallbackFcn(app, @dq0EditFieldValueChanged, true);
            app.dq0EditField_3Label = uilabel(app.GeneralSettingsTab);
            app.dq0EditField_3Label.Text = 'dq₅(0) ';
            app.dq0EditField_3 = uieditfield(app.GeneralSettingsTab, 'numeric');
            app.dq0EditField_3.ValueChangedFcn = createCallbackFcn(app, @dq0EditField_3ValueChanged, true);
            app.mLabel = uilabel(app.GeneralSettingsTab);
            app.mLabel.Text = '[m]';
            app.mLabel_8 = uilabel(app.GeneralSettingsTab);
            app.mLabel_8.Text = '[m]';
            app.mLabel_9 = uilabel(app.GeneralSettingsTab);
            app.mLabel_9.Text = '[m]';
            app.Label_3 = uilabel(app.GeneralSettingsTab);
            app.Label_3.Text = '[°]';
            app.Label_4 = uilabel(app.GeneralSettingsTab);
            app.Label_4.Text = '[°]';
            app.Label_5 = uilabel(app.GeneralSettingsTab);
            app.Label_5.Text = '[°]';
            app.msLabel_2 = uilabel(app.GeneralSettingsTab);
            app.msLabel_2.Text = '[m/s]';
            app.msLabel_3 = uilabel(app.GeneralSettingsTab);
            app.msLabel_3.Text = '[m/s]';
            app.sLabel_8 = uilabel(app.GeneralSettingsTab);
            app.sLabel_8.Text = '[°/s]';
            app.sLabel_9 = uilabel(app.GeneralSettingsTab);
            app.sLabel_9.Text = '[°/s]';
            app.sLabel_10 = uilabel(app.GeneralSettingsTab);
            app.sLabel_10.Text = '[°/s]';
            app.SetSeaPropertiesLabel = uilabel(app.GeneralSettingsTab);
            app.SetSeaPropertiesLabel.Text = 'Set Sea Properties';
            app.WaterDensityEditFieldLabel = uilabel(app.GeneralSettingsTab);
            app.WaterDensityEditFieldLabel.Text = 'Water Density';
            app.WaterDensityEditField = uieditfield(app.GeneralSettingsTab, 'numeric');
            app.WaterDensityEditField.ValueChangedFcn = createCallbackFcn(app, @WaterDensityEditFieldValueChanged, true);
            app.kgmLabel = uilabel(app.GeneralSettingsTab);
            app.kgmLabel.Text = '[kg/m³]';
            app.SetWavePropertiesLabel = uilabel(app.GeneralSettingsTab);
            app.SetWavePropertiesLabel.Text = 'Set Wave Properties';
            app.AmplitudeEditFieldLabel = uilabel(app.GeneralSettingsTab);
            app.AmplitudeEditFieldLabel.Text = 'Amplitude';
            app.AmplitudeEditField = uieditfield(app.GeneralSettingsTab, 'numeric');
            app.AmplitudeEditField.ValueChangedFcn = createCallbackFcn(app, @AmplitudeEditFieldValueChanged, true);
            app.AmplitudeEditField.Limits = [0 Inf];
            app.FrequencyEditFieldLabel = uilabel(app.GeneralSettingsTab);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.GeneralSettingsTab, 'numeric');
            app.FrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @FrequencyEditFieldValueChanged, true);
            app.mLabel_7 = uilabel(app.GeneralSettingsTab);
            app.mLabel_7.Text = '[m]';
            app.HzLabel = uilabel(app.GeneralSettingsTab);
            app.HzLabel.Text = '[Hz]';
            app.UIAxes2 = uiaxes(app.GeneralSettingsTab);
            title(app.UIAxes2, 'Background Image');
            app.WAVEGliderSimulatorLabel = uilabel(app.GeneralSettingsTab);
            app.WAVEGliderSimulatorLabel.Text = 'WAVE Glider Simulator';
            app.WingProfileTab = uitab(app.TabGroup);
            app.WingProfileTab.Title = 'Wing Profile';
            app.WingProfileTab.Units = 'pixels';
            app.UIAxes = uiaxes(app.WingProfileTab);
            title(app.UIAxes, 'Wing Profile');
            xlabel(app.UIAxes, 'Width [m]');
            ylabel(app.UIAxes, 'Length [m]');
            app.TypeDropDownLabel = uilabel(app.WingProfileTab);
            app.TypeDropDownLabel.Text = 'Type';
            app.TypeDropDown = uidropdown(app.WingProfileTab);
            app.TypeDropDown.Items = {'Trapezoidal', 'Exponential'};
            app.TypeDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeDropDownValueChanged, true);
            app.TypeDropDown.Value = 'Trapezoidal';
            app.SetWingProfileLabel = uilabel(app.WingProfileTab);
            app.SetWingProfileLabel.Text = 'Set Wing Profile';
            app.SetWingDimensionsLabel = uilabel(app.WingProfileTab);
            app.SetWingDimensionsLabel.Text = 'Set Wing Dimensions';
            app.MaxWidthEditFieldLabel = uilabel(app.WingProfileTab);
            app.MaxWidthEditFieldLabel.Text = 'Max Width';
            app.MaxWidthEditField = uieditfield(app.WingProfileTab, 'numeric');
            app.MaxWidthEditField.ValueChangedFcn = createCallbackFcn(app, @MaxWidthEditFieldValueChanged, true);
            app.MaxWidthEditField.Limits = [0 Inf];
            app.MinWidthEditFieldLabel = uilabel(app.WingProfileTab);
            app.MinWidthEditFieldLabel.Text = 'Min Width';
            app.MinWidthEditField = uieditfield(app.WingProfileTab, 'numeric');
            app.MinWidthEditField.ValueChangedFcn = createCallbackFcn(app, @MinWidthEditFieldValueChanged, true);
            app.MinWidthEditField.Limits = [0 Inf];
            app.MaxLengthEditFieldLabel = uilabel(app.WingProfileTab);
            app.MaxLengthEditFieldLabel.Text = 'Max Length';
            app.MaxLengthEditField = uieditfield(app.WingProfileTab, 'numeric');
            app.MaxLengthEditField.ValueChangedFcn = createCallbackFcn(app, @MaxLengthEditFieldValueChanged, true);
            app.MaxLengthEditField.Limits = [0 Inf];
            app.MinLengthEditFieldLabel = uilabel(app.WingProfileTab);
            app.MinLengthEditFieldLabel.Text = 'Min Length';
            app.MinLengthEditField = uieditfield(app.WingProfileTab, 'numeric');
            app.MinLengthEditField.ValueChangedFcn = createCallbackFcn(app, @MinLengthEditFieldValueChanged, true);
            app.MinLengthEditField.Limits = [0 Inf];
            app.mLabel_3 = uilabel(app.WingProfileTab);
            app.mLabel_3.Text = '[m]';
            app.mLabel_4 = uilabel(app.WingProfileTab);
            app.mLabel_4.Text = '[m]';
            app.mLabel_5 = uilabel(app.WingProfileTab);
            app.mLabel_5.Text = '[m]';
            app.mLabel_6 = uilabel(app.WingProfileTab);
            app.mLabel_6.Text = '[m]';
            app.EditFieldLabel = uilabel(app.WingProfileTab);
            app.EditFieldLabel.Text = 'α';
            app.EditField = uieditfield(app.WingProfileTab, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.ExponentialProfileLabel = uilabel(app.WingProfileTab);
            app.ExponentialProfileLabel.Text = 'Exponential Profile';
            app.SetMountingPointLabel = uilabel(app.WingProfileTab);
            app.SetMountingPointLabel.Text = 'Set Mounting Point';
            app.MountingPointDropDownLabel = uilabel(app.WingProfileTab);
            app.MountingPointDropDownLabel.Text = 'Mounting Point';
            app.MountingPointDropDown = uidropdown(app.WingProfileTab);
            app.MountingPointDropDown.Items = {'Top', 'Center', 'Bottom'};
            app.MountingPointDropDown.ValueChangedFcn = createCallbackFcn(app, @MountingPointDropDownValueChanged, true);
            app.MountingPointDropDown.Value = 'Center';
            app.SetWingMountingDirectionLabel = uilabel(app.WingProfileTab);
            app.SetWingMountingDirectionLabel.Text = 'Set Wing Mounting Direction';
            app.MountingDirectionButtonGroup = uibuttongroup(app.WingProfileTab);
            app.MountingDirectionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @MountingDirectionButtonGroupSelectionChanged, true);
            app.MountingDirectionButtonGroup.Title = 'Mounting Direction';
            app.StraightButton = uiradiobutton(app.MountingDirectionButtonGroup);
            app.StraightButton.Text = 'Straight';
            app.StraightButton.Value = true;
            app.ReverseButton = uiradiobutton(app.MountingDirectionButtonGroup);
            app.ReverseButton.Text = 'Reverse';
            app.UIAxes3 = uiaxes(app.WingProfileTab);
            title(app.UIAxes3, 'Background Image');
            app.WAVEGliderSimulatorLabel_2 = uilabel(app.WingProfileTab);
            app.WAVEGliderSimulatorLabel_2.Text = 'WAVE Glider Simulator';
            app.Label_6 = uilabel(app.WingProfileTab);
            app.Label_6.Text = '[ ]';
            app.BrakeOptionsTab = uitab(app.TabGroup);
            app.BrakeOptionsTab.Title = 'Brake Options';
            app.BrakeOptionsTab.Units = 'pixels';
            app.BrakeButtonGroup = uibuttongroup(app.BrakeOptionsTab);
            app.BrakeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @BrakeButtonGroupSelectionChanged, true);
            app.BrakeButtonGroup.Title = 'Brake';
            app.RechargeButton = uitogglebutton(app.BrakeButtonGroup);
            app.RechargeButton.Text = 'Recharge';
            app.PropulsionButton = uitogglebutton(app.BrakeButtonGroup);
            app.PropulsionButton.Text = 'Propulsion';
            app.PropulsionButton.Value = true;
            app.BrakeActionButtonGroup = uibuttongroup(app.BrakeOptionsTab);
            app.BrakeActionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @BrakeActionButtonGroupSelectionChanged, true);
            app.BrakeActionButtonGroup.Title = 'Brake Action';
            app.B1Button = uiradiobutton(app.BrakeActionButtonGroup);
            app.B1Button.Text = 'B1';
            app.B2Button = uiradiobutton(app.BrakeActionButtonGroup);
            app.B2Button.Text = 'B2';
            app.B2Button.Value = true;
            app.B3Button = uiradiobutton(app.BrakeActionButtonGroup);
            app.B3Button.Text = 'B3';
            app.B4Button = uiradiobutton(app.BrakeActionButtonGroup);
            app.B4Button.Text = 'B4';
            app.NoticeTextAreaLabel = uilabel(app.BrakeOptionsTab);
            app.NoticeTextAreaLabel.Text = 'Notice';
            app.NoticeTextArea = uitextarea(app.BrakeOptionsTab);
            app.NoticeTextArea.Editable = 'off';
            app.NoticeTextArea.Value = {'Rechargin'' mode implies no brake action.'; ''};
            app.BrakeConditionsTextAreaLabel = uilabel(app.BrakeOptionsTab);
            app.BrakeConditionsTextAreaLabel.Text = 'Brake Conditions';
            app.BrakeConditionsTextArea = uitextarea(app.BrakeOptionsTab);
            app.BrakeConditionsTextArea.Editable = 'off';
            app.BrakeConditionsTextArea.Value = {'B1 := v(1) > 0'; 'B2 := v(1) > 0 && q(4) > π/2 && |dq(4)| ≤ 1e-2'; 'B3 := v(1) > 0 && v(2) > 0'; 'B4 := v(1) > 0 && q(4) > π/2'; ''; 'Default Brake: B2'};
            app.SetBrakePreferencesLabel = uilabel(app.BrakeOptionsTab);
            app.SetBrakePreferencesLabel.Text = 'Set Brake Preferences';
            app.UIAxes4 = uiaxes(app.BrakeOptionsTab);
            title(app.UIAxes4, 'Background Image');
            app.WAVEGliderSimulatorLabel_3 = uilabel(app.BrakeOptionsTab);
            app.WAVEGliderSimulatorLabel_3.Text = 'WAVE Glider Simulator';
            app.AnalysisTab = uitab(app.TabGroup);
            app.AnalysisTab.Title = 'Analysis';
            app.AnalysisTab.Units = 'pixels';
            app.StartSimulationButton = uibutton(app.AnalysisTab, 'push');
            app.StartSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @StartSimulationButtonPushed, true);
            app.StartSimulationButton.Text = 'Start Simulation';
            app.AnimationButton = uibutton(app.AnalysisTab, 'push');
            app.AnimationButton.ButtonPushedFcn = createCallbackFcn(app, @AnimationButtonPushed, true);
            app.AnimationButton.Text = 'Animation';
            app.PlotsButton = uibutton(app.AnalysisTab, 'push');
            app.PlotsButton.ButtonPushedFcn = createCallbackFcn(app, @PlotsButtonPushed, true);
            app.PlotsButton.Text = 'Plots';
            app.AnalysisToolLabel = uilabel(app.AnalysisTab);
            app.AnalysisToolLabel.Text = 'Analysis Tool';
            app.SetTimePreferencesLabel = uilabel(app.AnalysisTab);
            app.SetTimePreferencesLabel.Text = 'Set Time Preferences';
            app.StepTimeEditFieldLabel = uilabel(app.AnalysisTab);
            app.StepTimeEditFieldLabel.Text = 'Step Time';
            app.StepTimeEditField = uieditfield(app.AnalysisTab, 'numeric');
            app.StepTimeEditField.LowerLimitInclusive = 'off';
            app.StepTimeEditField.ValueChangedFcn = createCallbackFcn(app, @StepTimeEditFieldValueChanged, true);
            app.StepTimeEditField.Limits = [0 Inf];
            app.StepTimeEditField.Value = 1;
            app.SimulationTimeEditFieldLabel = uilabel(app.AnalysisTab);
            app.SimulationTimeEditFieldLabel.Text = 'Simulation Time';
            app.SimulationTimeEditField = uieditfield(app.AnalysisTab, 'numeric');
            app.SimulationTimeEditField.LowerLimitInclusive = 'off';
            app.SimulationTimeEditField.ValueChangedFcn = createCallbackFcn(app, @SimulationTimeEditFieldValueChanged, true);
            app.SimulationTimeEditField.Limits = [0 Inf];
            app.SimulationTimeEditField.Value = 1;
            app.sLabel_3 = uilabel(app.AnalysisTab);
            app.sLabel_3.Text = '[s]';
            app.sLabel_4 = uilabel(app.AnalysisTab);
            app.sLabel_4.Text = '[s]';
            app.TransientTimeEditFieldLabel = uilabel(app.AnalysisTab);
            app.TransientTimeEditFieldLabel.Text = 'Transient Time';
            app.TransientTimeEditField = uieditfield(app.AnalysisTab, 'numeric');
            app.TransientTimeEditField.ValueChangedFcn = createCallbackFcn(app, @TransientTimeEditFieldValueChanged, true);
            app.TransientTimeEditField.Limits = [0 Inf];
            app.sLabel_5 = uilabel(app.AnalysisTab);
            app.sLabel_5.Text = '[s]';
            app.DisplacementVelocityEditFieldLabel = uilabel(app.AnalysisTab);
            app.DisplacementVelocityEditFieldLabel.Text = 'Displacement Velocity';
            app.DisplacementVelocityEditField = uieditfield(app.AnalysisTab, 'numeric');
            app.DisplacementVelocityEditField.ValueDisplayFormat = '%11.3g';
            app.DisplacementVelocityEditField.Editable = 'off';
            app.NBTextAreaLabel = uilabel(app.AnalysisTab);
            app.NBTextAreaLabel.Text = 'N.B.:';
            app.NBTextArea = uitextarea(app.AnalysisTab);
            app.NBTextArea.Editable = 'off';
            app.NBTextArea.Value = {'The evaluation of the Displacement Velocity should be performed only for simulation at least of 60 seconds.'; 'Notice that Transient Time has to be less than Simulation Time.'; ''; 'Refresh Button works only in case of updated simulator version workspaces. Do not perform using old workspaces!'; ''};
            app.EvaluateDisplacementVelocityLabel = uilabel(app.AnalysisTab);
            app.EvaluateDisplacementVelocityLabel.Text = 'Evaluate Displacement Velocity';
            app.EvaluateButton = uibutton(app.AnalysisTab, 'push');
            app.EvaluateButton.ButtonPushedFcn = createCallbackFcn(app, @EvaluateButtonPushed, true);
            app.EvaluateButton.Text = 'Evaluate';
            app.msLabel = uilabel(app.AnalysisTab);
            app.msLabel.Text = '[m/s]';
            app.UIAxes5 = uiaxes(app.AnalysisTab);
            title(app.UIAxes5, 'Background Image');
            app.WAVEGliderSimulatorLabel_4 = uilabel(app.AnalysisTab);
            app.WAVEGliderSimulatorLabel_4.Text = 'WAVE Glider Simulator';
            app.RefreshButton = uibutton(app.AnalysisTab, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.RefreshGUIfieldLabel = uilabel(app.AnalysisTab);
            app.RefreshGUIfieldLabel.Text = 'Refresh GUI field';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI_Simulator()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @ParameterInitializationFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end